classdef realTimeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuroARTUIFigure                matlab.ui.Figure
        SynchronyAnalysisCheckBox       matlab.ui.control.CheckBox
        GroupIDDropDown                 matlab.ui.control.DropDown
        GroupIDDropDownLabel            matlab.ui.control.Label
        AdjustwindowsizePanel           matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        UseacumulativewindowCheckBox    matlab.ui.control.CheckBox
        CorrelationAnalysisWindowsecondsSlider  matlab.ui.control.Slider
        CorrelationAnalysisWindowsecondsSliderLabel  matlab.ui.control.Label
        kHzLabel                        matlab.ui.control.Label
        FrameNoEditField                matlab.ui.control.NumericEditField
        FrameNoEditFieldLabel           matlab.ui.control.Label
        StimulateButton                 matlab.ui.control.Button
        StartRealTimeAnalysisButton     matlab.ui.control.Button
        TuningAnalysisCheckBox          matlab.ui.control.CheckBox
        RedChannelCheckBox              matlab.ui.control.CheckBox
        CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RealTimeAnalysisTab             matlab.ui.container.Tab
        Panel_5                         matlab.ui.container.Panel
        LoadButton_2                    matlab.ui.control.Button
        UITable_2                       matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        LoadButton                      matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        Panel_3                         matlab.ui.container.Panel
        SelectforstimulationCheckBox_2  matlab.ui.control.CheckBox
        UIAxes_selected                 matlab.ui.control.UIAxes
        Panel_4                         matlab.ui.container.Panel
        SelectTopSpinner                matlab.ui.control.Spinner
        SelectTopSpinnerLabel           matlab.ui.control.Label
        SelectforstimulationCheckBox    matlab.ui.control.CheckBox
        UIAxes_best                     matlab.ui.control.UIAxes
        BoxScalingTab                   matlab.ui.container.Tab
        KcEditField                     matlab.ui.control.NumericEditField
        KcEditFieldLabel                matlab.ui.control.Label
        MinneuronsperwindowEditField    matlab.ui.control.NumericEditField
        MinneuronsperwindowEditFieldLabel  matlab.ui.control.Label
        OverlapfactorEditField          matlab.ui.control.NumericEditField
        OverlapfactorEditFieldLabel     matlab.ui.control.Label
        NoofwindowsizesDropDown         matlab.ui.control.DropDown
        NoofwindowsizesDropDownLabel    matlab.ui.control.Label
        CorrelationsDropDown            matlab.ui.control.DropDown
        CorrelationsDropDownLabel       matlab.ui.control.Label
        DistancestepsizeEditField       matlab.ui.control.NumericEditField
        DistancestepsizeEditFieldLabel  matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        typeDropDown                    matlab.ui.control.DropDown
        typeDropDownLabel               matlab.ui.control.Label
        micronsPerPixelEditField        matlab.ui.control.NumericEditField
        micronsPerPixelEditFieldLabel   matlab.ui.control.Label
        dimYEditField                   matlab.ui.control.NumericEditField
        dimYEditFieldLabel              matlab.ui.control.Label
        dimXEditField                   matlab.ui.control.NumericEditField
        dimXEditFieldLabel              matlab.ui.control.Label
        averagingFactorEditField        matlab.ui.control.NumericEditField
        averagingFactorEditFieldLabel   matlab.ui.control.Label
        stopFrameEditField              matlab.ui.control.NumericEditField
        stopFrameEditFieldLabel         matlab.ui.control.Label
        startFrameEditField             matlab.ui.control.NumericEditField
        startFrameEditFieldLabel        matlab.ui.control.Label
        EvaluateScalingButton           matlab.ui.control.Button
        AutoSelectButton                matlab.ui.control.Button
        CenterthewindowCheckBox         matlab.ui.control.CheckBox
        UITable_3                       matlab.ui.control.Table
        HzLabel                         matlab.ui.control.Label
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        StimulationBNSTab               matlab.ui.container.Tab
        IterationsEditField             matlab.ui.control.NumericEditField
        IterationsEditFieldLabel        matlab.ui.control.Label
        DownloadtoSLMButton             matlab.ui.control.Button
        EnableSequenceModeCheckBox      matlab.ui.control.CheckBox
        IntervalmsEditField             matlab.ui.control.NumericEditField
        IntervalmsEditFieldLabel        matlab.ui.control.Label
        PixelsLabel_2                   matlab.ui.control.Label
        PixelsLabel                     matlab.ui.control.Label
        YShiftEditField                 matlab.ui.control.NumericEditField
        YShiftEditFieldLabel            matlab.ui.control.Label
        XShiftEditField                 matlab.ui.control.NumericEditField
        XShiftEditFieldLabel            matlab.ui.control.Label
        PhaseMaskCenteringLabel         matlab.ui.control.Label
        VirtualLensEditField            matlab.ui.control.NumericEditField
        VirtualLensEditFieldLabel       matlab.ui.control.Label
        GSIterationsEditField           matlab.ui.control.NumericEditField
        GSIterationsEditFieldLabel      matlab.ui.control.Label
        VortexModeEditField             matlab.ui.control.NumericEditField
        VortexModeEditFieldLabel        matlab.ui.control.Label
        UIAxes8_2                       matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        StimulationDMDTab               matlab.ui.container.Tab
        Group1SizeEditField             matlab.ui.control.NumericEditField
        Group1SizeEditFieldLabel        matlab.ui.control.Label
        StimGroupEditField              matlab.ui.control.NumericEditField
        StimGroupEditFieldLabel         matlab.ui.control.Label
        BackgroundIntensityEditField    matlab.ui.control.NumericEditField
        BackgroundIntensityEditFieldLabel  matlab.ui.control.Label
        RadiuspixelsEditField           matlab.ui.control.NumericEditField
        RadiuspixelsEditFieldLabel      matlab.ui.control.Label
        IterationsEditField_2           matlab.ui.control.NumericEditField
        IterationsEditField_2Label      matlab.ui.control.Label
        RepeatEverymsEditField          matlab.ui.control.NumericEditField
        RepeatEverymsEditFieldLabel     matlab.ui.control.Label
        PulseDurationmsEditField        matlab.ui.control.NumericEditField
        PulseDurationmsEditFieldLabel   matlab.ui.control.Label
        EnableTestModeCheckBox          matlab.ui.control.CheckBox
        EnableSequenceModeCheckBox_2    matlab.ui.control.CheckBox
        PrairieLinkTab                  matlab.ui.container.Tab
        IdentifyStimulatableCellsPanel  matlab.ui.container.Panel
        ClickStarttoidentifystimulatablecellsLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        PhotostimulationSwitch          matlab.ui.control.Switch
        PhotostimulationSwitchLabel     matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        IPaddressEditField              matlab.ui.control.EditField
        IPaddressEditFieldLabel         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        LaserPowerEditField             matlab.ui.control.NumericEditField
        LaserPowerEditFieldLabel        matlab.ui.control.Label
        LaserNameEditField              matlab.ui.control.EditField
        LaserNameEditFieldLabel         matlab.ui.control.Label
        DelaymsEditField_2              matlab.ui.control.NumericEditField
        DelaymsEditField_2Label         matlab.ui.control.Label
        TriggerCountSpinner             matlab.ui.control.Spinner
        TriggerCountSpinnerLabel        matlab.ui.control.Label
        TriggerDropDown                 matlab.ui.control.DropDown
        TriggerDropDownLabel            matlab.ui.control.Label
        SpiralRevolutionsSpinner        matlab.ui.control.Spinner
        SpiralRevolutionsSpinnerLabel   matlab.ui.control.Label
        SpiralSizeSlider                matlab.ui.control.Slider
        SpiralSizeSliderLabel           matlab.ui.control.Label
        PatternDropDown                 matlab.ui.control.DropDown
        PatternDropDownLabel            matlab.ui.control.Label
        ZoffsetEditField                matlab.ui.control.NumericEditField
        ZoffsetEditFieldLabel           matlab.ui.control.Label
        DStimulationDropDown            matlab.ui.control.DropDown
        DStimulationDropDownLabel       matlab.ui.control.Label
        NoofTargetsSpinner              matlab.ui.control.Spinner
        NoofTargetsSpinnerLabel         matlab.ui.control.Label
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public) 
        imTemplate
        coordinates
        DFF 
        npBWout 
        rawFluo 
        roiBW2
        xcRaw 
        ycRaw 
        norm_meanIMG
        symmFLAG
        smoothwin
        r
        dffwindow
        percent
        last_init_frame
        displayWin
        batch_size 
        rank1
        rank2
        rank3
        select1
        select2
        select3
        selectedCellList
        selectedCellsAll
        selectedCellFrameID
        selectQueue
        hidden
        gap
        exptId
        exptVars
        filled
        minNpSubFluo
        maxAdjF
        numInitFrames
        mst
        mstWin
        numAvailFrames
        imagingFreq
        currentFr
        thorSyncFile
        psignalFile
        rawFileHandle
        greenChannel
        numChannels
        scope
        imgType
        ImageFile
        JiSignalInfo
        norm_meanRedIMG
        redChanDisplayed
        meanIMGhandle
        tstack
        xx
        yy
        selectedID
        loadCorrelated
        loadSelected
        groupId
        dll_name
        board_number
        SLM_ON
        SLM;
        stimShutter
        topRankedCellIDs
        target_cellIDs
        debugMode
        debugIndex
        stimulationParams
    end
    
    methods (Access = private)

        deletingROIs(app);

        ImageClickCallBack(app);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, RTparams)
            
            initializing();
        end

        % Button pushed function: StartRealTimeAnalysisButton
        function StartRealTimeAnalysisButtonPushed(app, event)
            
            totStart = tic;

            addpath(genpath('tools'));
            addpath(genpath('Psignal'));
            addpath(genpath('BNS_SLM'));

            warning off;
            
            settingRealTimeAnalysis();
                                    
            settinghowROIsShowUp();
            
            propertiesApp();
            
            TIFcells()
                
        end

        % Button pushed function: StimulateButton
        function StimulateButtonPushed(app, event)
            

            stimulateButton()

            
            % evalin('base', ['save(''', 'targets_', app.exptId ''',', '''', 'target_cells' ''')']); % save the coordinates to a .mat file
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            settingApplyButton();
        end

        % Value changed function: RedChannelCheckBox
        function RedChannelCheckBoxValueChanged(app, event)
            value = app.RedChannelCheckBox.Value;
            if value
               set(app.meanIMGhandle, 'CData', app.norm_meanRedIMG);
            else
               set(app.meanIMGhandle, 'CData', app.norm_meanIMG);
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loadCorrelated = 1;
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.loadSelected = 1;
        end

        % Button pushed function: AutoSelectButton
        function AutoSelectButtonPushed(app, event)
            settingAutoSelect();
        end

        % Button pushed function: EvaluateScalingButton
        function EvaluateScalingButtonPushed(app, event)
            settingEBS();
            
            % Plot C(r) for original data
            plotCr();

            % Calculate Kappa C
            
            calculateKappaC();
            
            
            app.UIAxes_2.Visible = 1;
            app.UIAxes_3.Visible = 1;
            
            hold(app.UIAxes_2,'off');
            hold(app.UIAxes_3,'off');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            % Determine which tab was selected
            selectedTab = app.TabGroup.SelectedTab;

            disablePrairieLink = 0;
            disableBNS = 0;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.debugMode = 1;
            app.debugIndex = 0;
            app.ClickStarttoidentifycellsthatrespondtophotostimulationLabel.Text = "Photostimulation started, please wait ...";
            
            if app.SelectforstimulationCheckBox.Value && app.SelectforstimulationCheckBox_2.Value
                cellIDs_selected = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
                cellIDs_correlated = app.topRankedCellIDs;
                cellIDs = union(cellIDs_correlated,cellIDs_selected); % to avoid duplicates
            elseif app.SelectforstimulationCheckBox_2.Value
                cellIDs = find(app.groupId == 1); % hard coded for now. change this to pick the selected groupID for stimulation
            elseif app.SelectforstimulationCheckBox.Value
                cellIDs = app.topRankedCellIDs;
            else
                return;
            end

            app.target_cellIDs = cellIDs;
            responses = zeros(length(cellIDs),1); % Initial condition: none of the cells respond to stimulation
            app.UITable2.Data = table(app.xcRaw(cellIDs),app.ycRaw(cellIDs),responses);

            params = struct();
            params.DStim = app.DStimulationDropDown.Value;
            params.zOffset = app.ZoffsetEditField.Value;
            params.isSpiral = 'False';
            if app.PatternDropDown.Value == "Spiral"
                params.isSpiral = 'True';
            end
            params.spiralSize = app.SpiralSizeSlider.Value;
            params.spiralRevolutions = app.SpiralRevolutionsSpinner.Value;
            params.trigger = app.TriggerDropDown.Value;
            params.triggerCount = app.TriggerCountSpinner.Value;
            params.delay = app.DelaymsEditField_2.Value;
            params.setSize = min(app.NoofTargetsSpinner.Value,nTargets);
            params.duration = app.DurationEditField.Value;
            params.LaserName = app.LaserNameEditField.Value;
            params.power = app.LaserPowerEditField.Value;
            params.IPaddress = app.IPaddressEditField.Value;

            app.stimulationParams = params; % parameters to be used during stimulation

        end

        % Button pushed function: DownloadtoSLMButton
        function DownloadtoSLMButtonPushed(app, event)
            settingSLM()
            
            SLMButton();
        end

        % Value changed function: EnableSequenceModeCheckBox
        function EnableSequenceModeCheckBoxValueChanged(app, event)
            value = app.EnableSequenceModeCheckBox.Value;
            if app.SLM == "BNS" % need to wait until the phase masks are loaded to the SLM (BNS SLM only)
                if value
                    app.StimulateButton.Enable = 0;
                else
                    app.StimulateButton.Enable = 1;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NeuroARTUIFigure = uifigure('Visible', 'off');
            app.NeuroARTUIFigure.Name = 'NeuroART';
            app.UIAxes = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes.Visible = 'off';
            app.UIAxes7 = uiaxes(app.NeuroARTUIFigure);
            app.UIAxes7.XTick = [];
            app.UIAxes7.YTick = [];
            app.UIAxes9 = uiaxes(app.NeuroARTUIFigure);
            title(app.UIAxes9, 'Synchrony')
            xlabel(app.UIAxes9, 'Time')
            ylabel(app.UIAxes9, 'Order')
            app.UIAxes9.XTick = [];
            app.UIAxes9.YTick = [];
            app.UIAxes9.Visible = 'off';
            app.TabGroup = uitabgroup(app.NeuroARTUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.RealTimeAnalysisTab = uitab(app.TabGroup);
            app.RealTimeAnalysisTab.Title = 'Real Time Analysis';
            app.Panel_4 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_best = uiaxes(app.Panel_4);
            title(app.UIAxes_best, 'DF/F (Most correlated  cells)')
            app.UIAxes_best.YTick = [];
            app.SelectforstimulationCheckBox = uicheckbox(app.Panel_4);
            app.SelectforstimulationCheckBox.Text = 'Select for stimulation';
            app.SelectTopSpinnerLabel = uilabel(app.Panel_4);
            app.SelectTopSpinnerLabel.Text = 'Select Top';
            app.SelectTopSpinner = uispinner(app.Panel_4);
            app.SelectTopSpinner.Limits = [3 Inf];
            app.SelectTopSpinner.ValueDisplayFormat = '%.0f';
            app.SelectTopSpinner.Value = 5;
            app.Panel_3 = uipanel(app.RealTimeAnalysisTab);
            app.UIAxes_selected = uiaxes(app.Panel_3);
            title(app.UIAxes_selected, 'DF/F (Selected cells)')
            app.UIAxes_selected.YTick = [];
            app.SelectforstimulationCheckBox_2 = uicheckbox(app.Panel_3);
            app.SelectforstimulationCheckBox_2.Text = 'Select for stimulation';
            app.SelectforstimulationCheckBox_2.Value = true;
            app.Panel_2 = uipanel(app.RealTimeAnalysisTab);
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.LoadButton = uibutton(app.Panel_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel_5 = uipanel(app.RealTimeAnalysisTab);
            app.UITable_2 = uitable(app.Panel_5);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'ID'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false true];
            app.LoadButton_2 = uibutton(app.Panel_5, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.BoxScalingTab = uitab(app.TabGroup);
            app.BoxScalingTab.Title = 'Box Scaling';
            app.UIAxes_2 = uiaxes(app.BoxScalingTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.BoxScalingTab);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.Visible = 'off';
            app.HzLabel = uilabel(app.BoxScalingTab);
            app.HzLabel.Text = 'Hz';
            app.UITable_3 = uitable(app.BoxScalingTab);
            app.UITable_3.ColumnName = {'Window Sizes'; 'Select'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.CenterthewindowCheckBox = uicheckbox(app.BoxScalingTab);
            app.CenterthewindowCheckBox.Text = 'Center the window';
            app.AutoSelectButton = uibutton(app.BoxScalingTab, 'push');
            app.AutoSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSelectButtonPushed, true);
            app.AutoSelectButton.Text = 'Auto Select';
            app.EvaluateScalingButton = uibutton(app.BoxScalingTab, 'push');
            app.EvaluateScalingButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateScalingButtonPushed, true);
            app.EvaluateScalingButton.Text = 'Evaluate Scaling';
            app.startFrameEditFieldLabel = uilabel(app.BoxScalingTab);
            app.startFrameEditFieldLabel.Text = 'startFrame';
            app.startFrameEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.startFrameEditField.Limits = [1 Inf];
            app.startFrameEditField.ValueDisplayFormat = '%.0f';
            app.startFrameEditField.Value = 1;
            app.stopFrameEditFieldLabel = uilabel(app.BoxScalingTab);
            app.stopFrameEditFieldLabel.Text = 'stopFrame';
            app.stopFrameEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.stopFrameEditField.Limits = [100 Inf];
            app.stopFrameEditField.ValueDisplayFormat = '%.0f';
            app.stopFrameEditField.Value = 2000;
            app.averagingFactorEditFieldLabel = uilabel(app.BoxScalingTab);
            app.averagingFactorEditFieldLabel.Text = 'averagingFactor';
            app.averagingFactorEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.averagingFactorEditField.Limits = [1 Inf];
            app.averagingFactorEditField.ValueDisplayFormat = '%.0f';
            app.averagingFactorEditField.Value = 1;
            app.dimXEditFieldLabel = uilabel(app.BoxScalingTab);
            app.dimXEditFieldLabel.Text = 'dimX';
            app.dimXEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.dimXEditField.Limits = [1 Inf];
            app.dimXEditField.ValueDisplayFormat = '%.0f';
            app.dimXEditField.Value = 512;
            app.dimYEditFieldLabel = uilabel(app.BoxScalingTab);
            app.dimYEditFieldLabel.Text = 'dimY';
            app.dimYEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.dimYEditField.Limits = [1 Inf];
            app.dimYEditField.ValueDisplayFormat = '%.0f';
            app.dimYEditField.Value = 512;
            app.micronsPerPixelEditFieldLabel = uilabel(app.BoxScalingTab);
            app.micronsPerPixelEditFieldLabel.Text = 'micronsPerPixel';
            app.micronsPerPixelEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.micronsPerPixelEditField.Limits = [0 Inf];
            app.micronsPerPixelEditField.Value = 0.8;
            app.typeDropDownLabel = uilabel(app.BoxScalingTab);
            app.typeDropDownLabel.Text = 'type';
            app.typeDropDown = uidropdown(app.BoxScalingTab);
            app.typeDropDown.Items = {'Linear', 'Log'};
            app.typeDropDown.Value = 'Linear';
            app.FrameRateEditFieldLabel = uilabel(app.BoxScalingTab);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate ';
            app.FrameRateEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.FrameRateEditField.Limits = [1 Inf];
            app.FrameRateEditField.Value = 30;
            app.DistancestepsizeEditFieldLabel = uilabel(app.BoxScalingTab);
            app.DistancestepsizeEditFieldLabel.Text = 'Distance step size';
            app.DistancestepsizeEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.DistancestepsizeEditField.Limits = [1 Inf];
            app.DistancestepsizeEditField.ValueDisplayFormat = '%.0f';
            app.DistancestepsizeEditField.Value = 5;
            app.CorrelationsDropDownLabel = uilabel(app.BoxScalingTab);
            app.CorrelationsDropDownLabel.Text = 'Correlations';
            app.CorrelationsDropDown = uidropdown(app.BoxScalingTab);
            app.CorrelationsDropDown.Items = {'Instantaneous', 'Pearson'};
            app.CorrelationsDropDown.Tooltip = {'Recommended option: instantaneous'};
            app.CorrelationsDropDown.Value = 'Instantaneous';
            app.NoofwindowsizesDropDownLabel = uilabel(app.BoxScalingTab);
            app.NoofwindowsizesDropDownLabel.Text = 'No: of window sizes';
            app.NoofwindowsizesDropDown = uidropdown(app.BoxScalingTab);
            app.NoofwindowsizesDropDown.Items = {'5', '8', '10', '25'};
            app.NoofwindowsizesDropDown.Value = '5';
            app.OverlapfactorEditFieldLabel = uilabel(app.BoxScalingTab);
            app.OverlapfactorEditFieldLabel.Text = 'Overlap factor';
            app.OverlapfactorEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.OverlapfactorEditField.Limits = [0 1];
            app.OverlapfactorEditField.Value = 0.5;
            app.MinneuronsperwindowEditFieldLabel = uilabel(app.BoxScalingTab);
            app.MinneuronsperwindowEditFieldLabel.Text = 'Min neurons per window';
            app.MinneuronsperwindowEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.MinneuronsperwindowEditField.Limits = [2 100];
            app.MinneuronsperwindowEditField.ValueDisplayFormat = '%.0f';
            app.MinneuronsperwindowEditField.Value = 5;
            app.KcEditFieldLabel = uilabel(app.BoxScalingTab);
            app.KcEditFieldLabel.Text = 'Kc';
            app.KcEditField = uieditfield(app.BoxScalingTab, 'numeric');
            app.StimulationBNSTab = uitab(app.TabGroup);
            app.StimulationBNSTab.Title = 'Stimulation (BNS)';
            app.UIAxes8 = uiaxes(app.StimulationBNSTab);
            title(app.UIAxes8, 'Phase Mask')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes8.XTick = [];
            app.UIAxes8.YTick = [];
            app.UIAxes8_2 = uiaxes(app.StimulationBNSTab);
            title(app.UIAxes8_2, 'Simulated Pattern')
            zlabel(app.UIAxes8_2, 'Z')
            app.UIAxes8_2.XTick = [];
            app.UIAxes8_2.YTick = [];
            app.VortexModeEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.VortexModeEditFieldLabel.Text = 'Vortex Mode';
            app.VortexModeEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.VortexModeEditField.Value = 7;
            app.GSIterationsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.GSIterationsEditFieldLabel.Text = 'GS Iterations';
            app.GSIterationsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.GSIterationsEditField.Value = 3;
            app.VirtualLensEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.VirtualLensEditFieldLabel.Text = 'Virtual Lens';
            app.VirtualLensEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.VirtualLensEditField.Value = 400;
            app.PhaseMaskCenteringLabel = uilabel(app.StimulationBNSTab);
            app.PhaseMaskCenteringLabel.Text = 'Phase Mask Centering:';
            app.XShiftEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.XShiftEditFieldLabel.Text = 'XShift';
            app.XShiftEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.XShiftEditField.Value = 22;
            app.YShiftEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.YShiftEditFieldLabel.Text = 'YShift';
            app.YShiftEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.YShiftEditField.Value = 172;
            app.PixelsLabel = uilabel(app.StimulationBNSTab);
            app.PixelsLabel.Text = 'Pixels';
            app.PixelsLabel_2 = uilabel(app.StimulationBNSTab);
            app.PixelsLabel_2.Text = 'Pixels';
            app.IntervalmsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.IntervalmsEditFieldLabel.Text = 'Interval (ms)';
            app.IntervalmsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.IntervalmsEditField.Limits = [0 Inf];
            app.IntervalmsEditField.Value = 2000;
            app.EnableSequenceModeCheckBox = uicheckbox(app.StimulationBNSTab);
            app.EnableSequenceModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableSequenceModeCheckBoxValueChanged, true);
            app.EnableSequenceModeCheckBox.Text = 'Enable Sequence Mode';
            app.DownloadtoSLMButton = uibutton(app.StimulationBNSTab, 'push');
            app.DownloadtoSLMButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadtoSLMButtonPushed, true);
            app.DownloadtoSLMButton.Text = 'Download to SLM';
            app.IterationsEditFieldLabel = uilabel(app.StimulationBNSTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.StimulationBNSTab, 'numeric');
            app.IterationsEditField.Limits = [1 Inf];
            app.IterationsEditField.ValueDisplayFormat = '%.0f';
            app.IterationsEditField.Value = 50;
            app.StimulationDMDTab = uitab(app.TabGroup);
            app.StimulationDMDTab.Title = 'Stimulation (DMD)';
            app.EnableSequenceModeCheckBox_2 = uicheckbox(app.StimulationDMDTab);
            app.EnableSequenceModeCheckBox_2.Text = 'Enable Sequence Mode';
            app.EnableSequenceModeCheckBox_2.Value = true;
            app.EnableTestModeCheckBox = uicheckbox(app.StimulationDMDTab);
            app.EnableTestModeCheckBox.Text = 'Enable Test Mode';
            app.PulseDurationmsEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.PulseDurationmsEditFieldLabel.Text = 'Pulse Duration (ms)';
            app.PulseDurationmsEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.PulseDurationmsEditField.Value = 15;
            app.RepeatEverymsEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.RepeatEverymsEditFieldLabel.Text = 'Repeat Every (ms)';
            app.RepeatEverymsEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.RepeatEverymsEditField.Limits = [0 Inf];
            app.RepeatEverymsEditField.Value = 2000;
            app.IterationsEditField_2Label = uilabel(app.StimulationDMDTab);
            app.IterationsEditField_2Label.Text = 'Iterations';
            app.IterationsEditField_2 = uieditfield(app.StimulationDMDTab, 'numeric');
            app.IterationsEditField_2.Limits = [1 Inf];
            app.IterationsEditField_2.ValueDisplayFormat = '%.0f';
            app.IterationsEditField_2.Value = 45;
            app.RadiuspixelsEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.RadiuspixelsEditFieldLabel.Text = 'Radius (pixels)';
            app.RadiuspixelsEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.RadiuspixelsEditField.Value = 5;
            app.BackgroundIntensityEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.BackgroundIntensityEditFieldLabel.Text = 'Background Intensity';
            app.BackgroundIntensityEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.BackgroundIntensityEditField.Limits = [0 255];
            app.BackgroundIntensityEditField.ValueDisplayFormat = '%.0f';
            app.BackgroundIntensityEditField.Value = 10;
            app.StimGroupEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.StimGroupEditFieldLabel.Text = 'Stim Group';
            app.StimGroupEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.StimGroupEditField.Limits = [1 2];
            app.StimGroupEditField.ValueDisplayFormat = '%.0f';
            app.StimGroupEditField.Value = 1;
            app.Group1SizeEditFieldLabel = uilabel(app.StimulationDMDTab);
            app.Group1SizeEditFieldLabel.Text = 'Group1 Size';
            app.Group1SizeEditField = uieditfield(app.StimulationDMDTab, 'numeric');
            app.Group1SizeEditField.Limits = [1 10];
            app.Group1SizeEditField.ValueDisplayFormat = '%.0f';
            app.Group1SizeEditField.Value = 5;
            app.PrairieLinkTab = uitab(app.TabGroup);
            app.PrairieLinkTab.Title = 'PrairieLink';
            app.NoofTargetsSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.NoofTargetsSpinnerLabel.Text = 'No: of Targets';
            app.NoofTargetsSpinner = uispinner(app.PrairieLinkTab);
            app.NoofTargetsSpinner.Limits = [1 100];
            app.NoofTargetsSpinner.ValueDisplayFormat = '%.0f';
            app.NoofTargetsSpinner.Value = 1;
            app.DStimulationDropDownLabel = uilabel(app.PrairieLinkTab);
            app.DStimulationDropDownLabel.Text = '3D Stimulation';
            app.DStimulationDropDown = uidropdown(app.PrairieLinkTab);
            app.DStimulationDropDown.Items = {'True', 'False'};
            app.DStimulationDropDown.Value = 'False';
            app.ZoffsetEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.ZoffsetEditFieldLabel.Text = 'Z offset';
            app.ZoffsetEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.PatternDropDownLabel = uilabel(app.PrairieLinkTab);
            app.PatternDropDownLabel.Text = 'Pattern';
            app.PatternDropDown = uidropdown(app.PrairieLinkTab);
            app.PatternDropDown.Items = {'Spiral', 'Donut', 'Other'};
            app.PatternDropDown.Value = 'Spiral';
            app.SpiralSizeSliderLabel = uilabel(app.PrairieLinkTab);
            app.SpiralSizeSliderLabel.Text = 'Spiral Size';
            app.SpiralSizeSlider = uislider(app.PrairieLinkTab);
            app.SpiralSizeSlider.Limits = [0 1];
            app.SpiralRevolutionsSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.SpiralRevolutionsSpinnerLabel.Text = 'Spiral Revolutions';
            app.SpiralRevolutionsSpinner = uispinner(app.PrairieLinkTab);
            app.SpiralRevolutionsSpinner.Limits = [1 Inf];
            app.SpiralRevolutionsSpinner.ValueDisplayFormat = '%.0f';
            app.SpiralRevolutionsSpinner.Value = 1;
            app.TriggerDropDownLabel = uilabel(app.PrairieLinkTab);
            app.TriggerDropDownLabel.Text = 'Trigger';
            app.TriggerDropDown = uidropdown(app.PrairieLinkTab);
            app.TriggerDropDown.Items = {'None', 'PFI1', 'PFI8', 'TrigIn'};
            app.TriggerDropDown.Value = 'None';
            app.TriggerCountSpinnerLabel = uilabel(app.PrairieLinkTab);
            app.TriggerCountSpinnerLabel.Enable = 'off';
            app.TriggerCountSpinnerLabel.Text = 'Trigger Count';
            app.TriggerCountSpinner = uispinner(app.PrairieLinkTab);
            app.TriggerCountSpinner.Limits = [0 Inf];
            app.TriggerCountSpinner.ValueDisplayFormat = '%.0f';
            app.TriggerCountSpinner.Enable = 'off';
            app.DelaymsEditField_2Label = uilabel(app.PrairieLinkTab);
            app.DelaymsEditField_2Label.Text = 'Delay (ms)';
            app.DelaymsEditField_2 = uieditfield(app.PrairieLinkTab, 'numeric');
            app.LaserNameEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.LaserNameEditFieldLabel.Text = 'Laser Name';
            app.LaserNameEditField = uieditfield(app.PrairieLinkTab, 'text');
            app.LaserNameEditField.Value = 'PC-Stim';
            app.LaserPowerEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.LaserPowerEditFieldLabel.Text = 'Laser Power';
            app.LaserPowerEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.LaserPowerEditField.Limits = [0 Inf];
            app.LaserPowerEditField.Value = 50;
            app.DurationEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.PrairieLinkTab, 'numeric');
            app.IPaddressEditFieldLabel = uilabel(app.PrairieLinkTab);
            app.IPaddressEditFieldLabel.Text = 'IP address';
            app.IPaddressEditField = uieditfield(app.PrairieLinkTab, 'text');
            app.IPaddressEditField.Value = '10.99.5.51';
            app.IdentifyStimulatableCellsPanel = uipanel(app.PrairieLinkTab);
            app.IdentifyStimulatableCellsPanel.Title = 'Identify Stimulatable Cells';
            app.UITable2 = uitable(app.IdentifyStimulatableCellsPanel);
            app.UITable2.ColumnName = {'X'; 'Y'; 'Response'};
            app.UITable2.RowName = {};
            app.PhotostimulationSwitchLabel = uilabel(app.IdentifyStimulatableCellsPanel);
            app.PhotostimulationSwitchLabel.Text = 'Photostimulation';
            app.PhotostimulationSwitch = uiswitch(app.IdentifyStimulatableCellsPanel, 'slider');
            app.PhotostimulationSwitch.Items = {'Sequential', 'Simultaneous'};
            app.PhotostimulationSwitch.Value = 'Sequential';
            app.StartButton = uibutton(app.IdentifyStimulatableCellsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ClickStarttoidentifystimulatablecellsLabel = uilabel(app.IdentifyStimulatableCellsPanel);
            app.ClickStarttoidentifystimulatablecellsLabel.Text = 'Click "Start" to identify stimulatable cells';
            app.CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel = uilabel(app.NeuroARTUIFigure);
            app.CellRankings1Red2Green3Blue45MagentaSelectedcellsYellowLabel.Text = 'Cell Rankings: 1) Red 2) Green 3) Blue 4)5) Magenta;   Selected cells - Yellow';
            app.RedChannelCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @RedChannelCheckBoxValueChanged, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.TuningAnalysisCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.TuningAnalysisCheckBox.Text = 'Tuning Analysis';
            app.StartRealTimeAnalysisButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StartRealTimeAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartRealTimeAnalysisButtonPushed, true);
            app.StartRealTimeAnalysisButton.Text = 'Start Real Time Analysis';
            app.StimulateButton = uibutton(app.NeuroARTUIFigure, 'push');
            app.StimulateButton.ButtonPushedFcn = createCallbackFcn(app, @StimulateButtonPushed, true);
            app.StimulateButton.Text = 'Stimulate';
            app.FrameNoEditFieldLabel = uilabel(app.NeuroARTUIFigure);
            app.FrameNoEditFieldLabel.Text = 'Frame No:';
            app.FrameNoEditField = uieditfield(app.NeuroARTUIFigure, 'numeric');
            app.FrameNoEditField.ValueDisplayFormat = '%.0f';
            app.FrameNoEditField.Editable = 'off';
            app.kHzLabel = uilabel(app.NeuroARTUIFigure);
            app.kHzLabel.Text = 'kHz';
            app.AdjustwindowsizePanel = uipanel(app.NeuroARTUIFigure);
            app.AdjustwindowsizePanel.Title = 'Adjust window size';
            app.CorrelationAnalysisWindowsecondsSliderLabel = uilabel(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSliderLabel.Text = 'Correlation Analysis Window (seconds)';
            app.CorrelationAnalysisWindowsecondsSlider = uislider(app.AdjustwindowsizePanel);
            app.CorrelationAnalysisWindowsecondsSlider.Limits = [5 30];
            app.CorrelationAnalysisWindowsecondsSlider.MajorTicks = [5 10 15 20 25 30];
            app.CorrelationAnalysisWindowsecondsSlider.Value = 5;
            app.UseacumulativewindowCheckBox = uicheckbox(app.AdjustwindowsizePanel);
            app.UseacumulativewindowCheckBox.Text = 'Use a cumulative window';
            app.UseacumulativewindowCheckBox.Value = true;
            app.ApplyButton = uibutton(app.AdjustwindowsizePanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.GroupIDDropDownLabel = uilabel(app.NeuroARTUIFigure);
            app.GroupIDDropDownLabel.Text = 'Group ID';
            app.GroupIDDropDown = uidropdown(app.NeuroARTUIFigure);
            app.GroupIDDropDown.Items = {'1', '2', '3'};
            app.GroupIDDropDown.Value = '1';
            app.SynchronyAnalysisCheckBox = uicheckbox(app.NeuroARTUIFigure);
            app.SynchronyAnalysisCheckBox.Text = 'Synchrony Analysis';
            % Show the figure after all components are created
            app.NeuroARTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NeuroARTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuroARTUIFigure)
        end
    end
end